classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        AmptitudeSliderLabel  matlab.ui.control.Label
        AmptitudeSlider       matlab.ui.control.Slider
        FrequencySliderLabel  matlab.ui.control.Label
        FrequencySlider       matlab.ui.control.Slider
        ShiftSliderLabel      matlab.ui.control.Label
        ShiftSlider           matlab.ui.control.Slider
        UIAxes2               matlab.ui.control.UIAxes
        AKnobLabel            matlab.ui.control.Label
        AKnob                 matlab.ui.control.Knob
        DKnobLabel            matlab.ui.control.Label
        DKnob                 matlab.ui.control.Knob
        SKnobLabel            matlab.ui.control.Label
        SKnob                 matlab.ui.control.Knob
        RKnobLabel            matlab.ui.control.Label
        RKnob                 matlab.ui.control.Knob
        C0Button              matlab.ui.control.Button
        D0Button              matlab.ui.control.Button
        E0Button              matlab.ui.control.Button
        F0Button              matlab.ui.control.Button
        G0Button              matlab.ui.control.Button
        A0Button              matlab.ui.control.Button
        B0Button              matlab.ui.control.Button
        C1Button              matlab.ui.control.Button
        D1Button              matlab.ui.control.Button
        E1Button              matlab.ui.control.Button
        F1Button              matlab.ui.control.Button
        G1Button              matlab.ui.control.Button
        A1Button              matlab.ui.control.Button
        B1Button              matlab.ui.control.Button
        C2Button              matlab.ui.control.Button
        D2Button              matlab.ui.control.Button
        E2Button              matlab.ui.control.Button
        F2Button              matlab.ui.control.Button
        G2Button              matlab.ui.control.Button
        A2Button              matlab.ui.control.Button
        B2Button              matlab.ui.control.Button
        C3Button              matlab.ui.control.Button
        D3Button              matlab.ui.control.Button
        E3Button              matlab.ui.control.Button
        F3Button              matlab.ui.control.Button
        G3Button              matlab.ui.control.Button
        A3Button              matlab.ui.control.Button
        B3Button              matlab.ui.control.Button
        C4Button              matlab.ui.control.Button
        D4Button              matlab.ui.control.Button
        E4Button              matlab.ui.control.Button
        F4Button              matlab.ui.control.Button
        G4Button              matlab.ui.control.Button
        A4Button              matlab.ui.control.Button
        B4Button              matlab.ui.control.Button
        C5Button              matlab.ui.control.Button
        D5Button              matlab.ui.control.Button
        E5Button              matlab.ui.control.Button
        F5Button              matlab.ui.control.Button
        G5Button              matlab.ui.control.Button
        A5Button              matlab.ui.control.Button
        B5Button              matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Property % Description
        amp=0;
        shift=0;
        freq=0;
        x = -3*pi:0.01:3*pi;
        result=0;
        A_value=0;
        D_value=0;
        S_value=0;
        R_value=0;
        Aflag=0;
        Dflag=0;
        Sflag=0;
        Rflag=0;
        Noteflag=0;
    end


    methods (Access = public)
        
        function SoundPlay (app,value,str)
            if (strcmp('amp',str)==1)
                sound(value*sin(app.x))
                app.amp=value;
                app.result=value*sin(app.x);
            end 
            if (strcmp('freq',str)==1)
                sound(sin(app.x*value))
                app.freq=value;
                app.result=sin(app.x*value);
            end 
            if (strcmp('shift',str)==1)
                sound(sin(app.x+value))
                app.shift=value;
                app.result=sin(app.x+value);
            end 
            if(app.amp~=0 && app.freq~=0 &&app.shift~=0)
             sound(app.amp*sin((app.x*app.freq)+app.shift))
             app.result=app.amp*sin((app.x*app.freq)+app.shift);
            end 
            if(app.amp==0 && app.freq~=0 &&app.shift~=0)
             sound(sin((app.x*app.freq)+app.shift))
             app.result=sin((app.x*app.freq)+app.shift);
            end 
            if(app.amp~=0 && app.freq==0 &&app.shift~=0)
             sound(app.amp*sin((app.x)+app.shift))
             app.result=app.amp*sin((app.x)+app.shift);
            end 
            if(app.amp~=0 && app.freq~=0 &&app.shift==0)
             sound(app.amp*sin((app.x*app.freq)))
             app.result=app.amp*sin((app.x*app.freq));
            end 
            UpdatePlots(app);
        end
        
        function UpdatePlots (app)
            if(app.amp~=0)
             plot(app.UIAxes,app.amp*sin(app.x))
            app.UIAxes.YLim=[-100 100]; 
             app.UIAxes.XLim=[0 100];
          % plot(app.UIAxes2,envelope(app.amp*sin(app.x),30,'analytic'))
            end 
          if(app.freq~=0)
                plot(app.UIAxes,sin(app.x*app.freq))
                 app.UIAxes.YLim=[-100 100];         
                 app.UIAxes.XLim=[0 100];
           %plot(app.UIAxes2,envelope(sin(app.x*app.freq),30,'analytic'))
            end 
             if(app.shift~=0)
                plot(app.UIAxes,sin(app.x+app.shift))
                 app.UIAxes.YLim=[-100 100];         
             app.UIAxes.XLim=[0 100];
           %plot(app.UIAxes2,envelope(sin(app.x+app.shift),30,'analytic'))
             end 
             if(app.amp~=0 && app.freq~=0 && app.shift~=0)
                  plot(app.UIAxes,app.amp*sin((app.x* app.freq)+app.shift))
                 app.UIAxes.YLim=[-100 100];         
             app.UIAxes.XLim=[0 100];
           %plot(app.UIAxes2,envelope(app.amp*sin((app.x* app.freq)+app.shift),30,'analytic'))
             end 
         if(app.amp~=0 && app.shift~=0 && app.freq==0)
                 plot(app.UIAxes,app.amp*sin((app.x)+app.shift))
                 app.UIAxes.YLim=[-100 100];         
             app.UIAxes.XLim=[0 100];
           %plot(app.UIAxes2,envelope(app.amp*sin((app.x)+app.shift),30,'analytic'))
         end 
          if(app.amp==0 && app.shift~=0 && app.freq~=0)
                 plot(app.UIAxes,sin((app.x*app.freq)+app.shift))
                 app.UIAxes.YLim=[-100 100];         
             app.UIAxes.XLim=[0 100];
          % plot(app.UIAxes2,envelope(sin((app.x*app.freq)+app.shift),30,'analytic'))
          end 
                    if(app.amp~=0 && app.shift==0 && app.freq~=0)
                        plot(app.UIAxes,app.amp*sin((app.x* app.freq)))
                 app.UIAxes.YLim=[-100 100];         
             app.UIAxes.XLim=[0 100];
          % plot(app.UIAxes2,envelope(app.amp*sin((app.x* app.freq)),30,'analytic'))
                    end 
        end
        
        function UpdatePlot2(app)
                       len=length(app.x);
            if(app.Aflag==1)
             if(app.D_value==0 && app.S_value==0 && app.R_value==0)
           newsignal=len*app.A_value*app.result*0.1;
          elseif(app.D_value~=0)
              newsignal=len*app.A_value*app.result*0.1*app.D_value;
          elseif(app.R_value~=0 )
              newsignal=len*app.A_value*app.result*0.1*app.R_value;
          elseif(app.S_value~=0)
              newsignal=len*app.A_value*app.result*0.1*app.S_value;
          elseif(app.D_value~=0 && app.S_value~=0)
                newsignal=len*app.A_value*app.result*0.1*app.S_value*app.D_value;
          elseif(app.D_value~=0 && app.R_value~=0)
              newsignal=len*app.A_value*app.result*0.1*app.R_value*app.D_value;
          elseif(app.S_value~=0 && app.R_value~=0)
              newsignal=len*app.A_value*app.result*0.1*app.S_value*app.R_value;
          elseif(app.D_value~=0 && app.R_value~=0 && app.S_value~=0)
                 newsignal=len*app.A_value*app.result*0.1*app.S_value*app.R_value*app.D_value;
             end 
           
             
            elseif(app.Dflag==1)
              if(app.A_value==0 && app.S_value==0 && app.R_value==0)
           newsignal=len*app.D_value*app.result*0.15;
          elseif(app.A_value~=0)
              newsignal=len*app.D_value*app.result*0.15*app.A_value;
          elseif(app.R_value~=0 )
              newsignal=len*app.D_value*app.result*0.15*app.R_value;
          elseif(app.S_value~=0)
              newsignal=len*app.D_value*app.result*0.15*app.S_value;
          elseif(app.A_value~=0 && app.S_value~=0)
                newsignal=len*app.D_value*app.result*0.15*app.S_value*app.A_value;
          elseif(app.A_value~=0 && app.R_value~=0)
              newsignal=len*app.D_value*app.result*0.15*app.R_value*app.A_value;
          elseif(app.S_value~=0 && app.R_value~=0)
              newsignal=len*app.D_value*app.result*0.15*app.S_value*app.R_value;
          elseif(app.A_value~=0 && app.R_value~=0 && app.S_value~=0)
                 newsignal=len*app.D_value*app.result*0.15*app.S_value*app.R_value*app.D_value;
          end 
              app.Dflag=0;
              
            
            elseif(app.Sflag==1)
              if(app.D_value==0 && app.A_value==0 && app.R_value==0)
           newsignal=len*app.S_value*app.result*0.6;
          elseif(app.D_value~=0)
              newsignal=len*app.S_value*app.result*0.6*app.D_value;
          elseif(app.R_value~=0 )
              newsignal=len*app.S_value*app.result*0.6*app.R_value;
          elseif(app.A_value~=0)
              newsignal=len*app.S_value*app.result*0.6*app.A_value;
          elseif(app.D_value~=0 && app.A_value~=0)
                newsignal=len*app.S_value*app.result*0.6*app.A_value*app.D_value;
          elseif(app.D_value~=0 && app.R_value~=0)
              newsignal=len*app.S_value*app.result*0.6*app.R_value*app.D_value;
          elseif(app.A_value~=0 && app.R_value~=0)
              newsignal=len*app.S_value*app.result*0.6*app.A_value*app.R_value;
          elseif(app.D_value~=0 && app.R_value~=0 && app.A_value~=0)
                 newsignal=len*app.S_value*app.result*0.6*app.A_value*app.R_value*app.D_value;
          end 
              
              app.Sflag=0;
            
            elseif(app.Rflag==1)
                if(app.D_value==0 && app.S_value==0 && app.A_value==0)
           newsignal=len*app.R_value*app.result*0.1;
          elseif(app.D_value~=0)
              newsignal=len*app.R_value*app.result*0.15*app.D_value;
          elseif(app.A_value~=0 )
              newsignal=len*app.R_value*app.result*0.15*app.A_value;
          elseif(app.S_value~=0)
              newsignal=len*app.R_value*app.result*0.15*app.S_value;
          elseif(app.D_value~=0 && app.S_value~=0)
                newsignal=len*app.R_value*app.result*0.15*app.S_value*app.D_value;
          elseif(app.D_value~=0 && app.A_value~=0)
              newsignal=len*app.R_value*app.result*0.1*app.A_value*app.D_value;
          elseif(app.S_value~=0 && app.A_value~=0)
              newsignal=len*app.R_value*app.result*0.15*app.S_value*app.A_value;
          elseif(app.D_value~=0 && app.A_value~=0 && app.S_value~=0)
                 newsignal=len*app.R_value*app.result*0.15*app.S_value*app.A_value*app.D_value;
                end 
                app.Rflag=0; 
          end 
          if(app.Aflag==0 && app.Dflag==0 && app.Sflag==0 && app.Rflag==0)
                newsignal=app.result;
          end 
%           if(app.Noteflag==1)
%             app.Noteflag=0; 
%             if(app.A_value~=0)
%                 app.Aflag=1;
%                 UpdatePlot2(app);
%             end 
%              if(app.D_value~=0)
%                 app.Dflag=1;
%                 UpdatePlot2(app);
%              end 
%               if(app.S_value~=0)
%                 app.Sflag=1;
%                 UpdatePlot2(app);
%               end 
%                if(app.R_value~=0)
%                 app.Rflag=1;
%                 UpdatePlot2(app);
%             end 
            
%           end 
          
           % newsignal=len*value*app.result*0.1; % attack is basiclly 10% of the total length of a signal
             plot(app.UIAxes2,newsignal)
             app.UIAxes2.XLim=[0 100];
                 app.UIAxes2.YLim=[-40000 40000];  
             
        end % end of function update plot 2
    
    
    end
  

    methods (Access = private)

        % Value changed function: AmptitudeSlider
        function AmptitudeSliderValueChanged(app, event)
            value = app.AmptitudeSlider.Value/10;
            str='amp';
            if value~=0
               SoundPlay(app,value,str);
        else 
            app.amp=0;
            end 
         app.Noteflag=1; 
               UpdatePlot2(app);
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
             app.Noteflag=1; 
            str='freq';
            if value~=0
               SoundPlay(app,value,str);
                              UpdatePlot2(app);

            else 
                app.freq=0;
            end 
             app.Noteflag=1; 
               UpdatePlot2(app);
            
        end

        % Value changed function: ShiftSlider
        function ShiftSliderValueChanged(app, event)

            value = app.ShiftSlider.Value;
             app.Noteflag=1; 
            str='shift';
            if value~=0
               SoundPlay(app,value,str);
                    UpdatePlot2(app);

            else 
                app.shift=0;
            end 
            app.Noteflag=1; 
               UpdatePlot2(app);
        end

        % Value changed function: AKnob
        function AKnobValueChanged(app, event)
            value = app.AKnob.Value;
             app.A_value=value;
             app.Aflag=1;
              if( app.Noteflag==0)
             if(app.AmptitudeSlider.Value~=0)
                 SoundPlay(app,app.AmptitudeSlider.Value/10,'amp');
             end 
              if(app.FrequencySlider.Value~=0)
                 SoundPlay(app,app.FrequencySlider.Value,'freq');
              end 
               if(app.ShiftSlider.Value~=0)
                 SoundPlay(app,app.ShiftSlider.Value,'shift');
               end
             elseif( app.Noteflag==1)
                  SoundPlay(app,app.freq,'freq');
             end 
         UpdatePlot2(app);
          
        end

        % Value changed function: DKnob
        function DKnobValueChanged(app, event)
           %0.15
            value = app.DKnob.Value;
             app.D_value=value;
             app.Dflag=1;
             if( app.Noteflag==0)
             if(app.AmptitudeSlider.Value~=0)
                 SoundPlay(app,app.AmptitudeSlider.Value/10,'amp');
             end 
              if(app.FrequencySlider.Value~=0)
                 SoundPlay(app,app.FrequencySlider.Value,'freq');
              end 
               if(app.ShiftSlider.Value~=0)
                 SoundPlay(app,app.ShiftSlider.Value,'shift');
               end
             elseif( app.Noteflag==1)
                  SoundPlay(app,app.freq,'freq');
             end 
           
             UpdatePlot2(app);

        end

        % Value changed function: SKnob
        function SKnobValueChanged(app, event)
           % Sustain is 0.6 of signal length  
            value = app.SKnob.Value;
            

             app.S_value=value;
          app.Sflag=1;
            if( app.Noteflag==0)
             if(app.AmptitudeSlider.Value~=0)
                 SoundPlay(app,app.AmptitudeSlider.Value/10,'amp');
             end 
              if(app.FrequencySlider.Value~=0)
                 SoundPlay(app,app.FrequencySlider.Value,'freq');
              end 
               if(app.ShiftSlider.Value~=0)
                 SoundPlay(app,app.ShiftSlider.Value,'shift');
               end
             elseif( app.Noteflag==1)
                  SoundPlay(app,app.freq,'freq');
             end 
          UpdatePlot2(app);
            
        end

        % Value changed function: RKnob
        function RKnobValueChanged(app, event)
            value = app.RKnob.Value;
            %    len=length(app.x);
                % R is 0.15 
      
             app.R_value=value;
        app.Rflag=1;
           if( app.Noteflag==0)
             if(app.AmptitudeSlider.Value~=0)
                 SoundPlay(app,app.AmptitudeSlider.Value/10,'amp');
             end 
              if(app.FrequencySlider.Value~=0)
                 SoundPlay(app,app.FrequencySlider.Value,'freq');
              end 
               if(app.ShiftSlider.Value~=0)
                 SoundPlay(app,app.ShiftSlider.Value,'shift');
               end
             elseif( app.Noteflag==1)
                  SoundPlay(app,app.freq,'freq');
             end 
        UpdatePlot2(app);
        end

        % Button pushed function: C0Button
        function C0ButtonPushed(app, event)
            app.freq=16.35;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
               UpdatePlot2(app);
               

        end

        % Button pushed function: D0Button
        function D0ButtonPushed(app, event)
            app.freq=18.35;
              SoundPlay(app,app.freq,'freq');
                           app.Noteflag=1; 
                   UpdatePlot2(app);
        end

        % Button pushed function: E0Button
        function E0ButtonPushed(app, event)
            app.freq= 20.60;
              SoundPlay(app,app.freq,'freq');
               app.Noteflag=1; 
                   UpdatePlot2(app);
        end

        % Button pushed function: F0Button
        function F0ButtonPushed(app, event)
            app.freq=21.83;
             SoundPlay(app,app.freq,'freq');
              app.Noteflag=1; 
                  UpdatePlot2(app);
        end

        % Button pushed function: G0Button
        function G0ButtonPushed(app, event)
            app.freq=24.50;
             SoundPlay(app,app.freq,'freq');
              app.Noteflag=1; 
                  UpdatePlot2(app);
        end

        % Button pushed function: A0Button
        function A0ButtonPushed(app, event)
            app.freq=27.50;
           SoundPlay(app,app.freq,'freq');
            app.Noteflag=1; 
                UpdatePlot2(app);
        end

        % Button pushed function: B0Button
        function B0ButtonPushed(app, event)
            app.freq=30.87;
               SoundPlay(app,app.freq,'freq');
                app.Noteflag=1; 
                    UpdatePlot2(app);
        end

        % Callback function
        function D4Button_2Pushed(app, event)
            app.freq=293.66;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function E4Button_2Pushed(app, event)
            app.freq=329.63;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function F4Button_2Pushed(app, event)
            app.freq=349.23;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function G4Button_3Pushed(app, event)
            app.freq=392;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function A4Button_2Pushed(app, event)
            app.freq=440;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function B4Button_2Pushed(app, event)
            app.freq= 493.88;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function C5Button_2Pushed(app, event)
            app.freq= 523.25;
             SoundPlay(app,app.freq,'freq');
              app.Noteflag=1; 
                  UpdatePlot2(app);
        end

        % Callback function
        function D5Button_2Pushed(app, event)
            app.freq= 587.33;
             SoundPlay(app,app.freq,'freq');
              app.Noteflag=1; 
                  UpdatePlot2(app);
        end

        % Callback function
        function E5Button_2Pushed(app, event)
            app.freq= 659.26;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function G5Button_2Pushed(app, event)
            app.freq= 783.99;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function A5Button_2Pushed(app, event)
            app.freq=880;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Callback function
        function B5Button_2Pushed(app, event)
            app.freq=988;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: C1Button
        function C1ButtonPushed(app, event)
             app.freq=32.7;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: D1Button
        function D1ButtonPushed(app, event)
             app.freq=36.71;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: E1Button
        function E1ButtonPushed(app, event)
             app.freq=41.20;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: F1Button
        function F1ButtonPushed(app, event)
             app.freq=43.65;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: G1Button
        function G1ButtonPushed(app, event)
             app.freq=49;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: A1Button
        function A1ButtonPushed(app, event)
             app.freq=55;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: B1Button
        function B1ButtonPushed(app, event)
             app.freq=61.74;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: C2Button
        function C2ButtonPushed(app, event)
             app.freq=65.41;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: D2Button
        function D2ButtonPushed(app, event)
             app.freq=73.41;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: E2Button
        function E2ButtonPushed(app, event)
             app.freq=82.41;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: F2Button
        function F2ButtonPushed(app, event)
             app.freq=87.31;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: G2Button
        function G2ButtonPushed(app, event)
               app.freq=98;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: A2Button
        function A2ButtonPushed(app, event)
               app.freq=110;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: B2Button
        function B2ButtonPushed(app, event)
               app.freq=123.47;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: C3Button
        function C3ButtonPushed(app, event)
               app.freq=130.81;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: D3Button
        function D3ButtonPushed(app, event)
               app.freq=146.83;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: E3Button
        function E3ButtonPushed(app, event)
               app.freq=164.81;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
               app.freq=174.61;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
               app.freq=196;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
               app.freq=220;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
               app.freq=246.94;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
               app.freq=261.63;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
               app.freq=293.66;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
               app.freq=329.63;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
               app.freq=349.23;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
               app.freq=392;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
               app.freq=440;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
               app.freq=493.88;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
               app.freq=523.25;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
               app.freq=587.33;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
               app.freq=659.25;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: F5Button
        function F5ButtonPushed(app, event)
               app.freq=698.46;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: G5Button
        function G5ButtonPushed(app, event)
               app.freq=783.99;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: A5Button
        function A5ButtonPushed(app, event)
               app.freq=880;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end

        % Button pushed function: B5Button
        function B5ButtonPushed(app, event)
               app.freq=987.77;
            SoundPlay(app,app.freq,'freq');
             app.Noteflag=1; 
                 UpdatePlot2(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sin Function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmptitudeSliderLabel = uilabel(app.UIFigure);
            app.AmptitudeSliderLabel.Text = 'Amptitude ';
            app.AmptitudeSlider = uislider(app.UIFigure);
            app.AmptitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmptitudeSliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.ShiftSliderLabel = uilabel(app.UIFigure);
            app.ShiftSliderLabel.Text = 'Shift';
            app.ShiftSlider = uislider(app.UIFigure);
            app.ShiftSlider.ValueChangedFcn = createCallbackFcn(app, @ShiftSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Envelope')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AKnobLabel = uilabel(app.UIFigure);
            app.AKnobLabel.Text = 'A';
            app.AKnob = uiknob(app.UIFigure, 'continuous');
            app.AKnob.Limits = [0 10];
            app.AKnob.ValueChangedFcn = createCallbackFcn(app, @AKnobValueChanged, true);
            app.DKnobLabel = uilabel(app.UIFigure);
            app.DKnobLabel.Text = 'D';
            app.DKnob = uiknob(app.UIFigure, 'continuous');
            app.DKnob.Limits = [0 10];
            app.DKnob.ValueChangedFcn = createCallbackFcn(app, @DKnobValueChanged, true);
            app.SKnobLabel = uilabel(app.UIFigure);
            app.SKnobLabel.Text = 'S';
            app.SKnob = uiknob(app.UIFigure, 'continuous');
            app.SKnob.Limits = [0 10];
            app.SKnob.ValueChangedFcn = createCallbackFcn(app, @SKnobValueChanged, true);
            app.RKnobLabel = uilabel(app.UIFigure);
            app.RKnobLabel.Text = 'R';
            app.RKnob = uiknob(app.UIFigure, 'continuous');
            app.RKnob.Limits = [0 10];
            app.RKnob.ValueChangedFcn = createCallbackFcn(app, @RKnobValueChanged, true);
            app.C0Button = uibutton(app.UIFigure, 'push');
            app.C0Button.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.C0Button.Text = 'C0';
            app.D0Button = uibutton(app.UIFigure, 'push');
            app.D0Button.ButtonPushedFcn = createCallbackFcn(app, @D0ButtonPushed, true);
            app.D0Button.Text = 'D0';
            app.E0Button = uibutton(app.UIFigure, 'push');
            app.E0Button.ButtonPushedFcn = createCallbackFcn(app, @E0ButtonPushed, true);
            app.E0Button.Text = 'E0';
            app.F0Button = uibutton(app.UIFigure, 'push');
            app.F0Button.ButtonPushedFcn = createCallbackFcn(app, @F0ButtonPushed, true);
            app.F0Button.Text = 'F0';
            app.G0Button = uibutton(app.UIFigure, 'push');
            app.G0Button.ButtonPushedFcn = createCallbackFcn(app, @G0ButtonPushed, true);
            app.G0Button.Text = 'G0';
            app.A0Button = uibutton(app.UIFigure, 'push');
            app.A0Button.ButtonPushedFcn = createCallbackFcn(app, @A0ButtonPushed, true);
            app.A0Button.Text = 'A0';
            app.B0Button = uibutton(app.UIFigure, 'push');
            app.B0Button.ButtonPushedFcn = createCallbackFcn(app, @B0ButtonPushed, true);
            app.B0Button.Text = 'B0';
            app.C1Button = uibutton(app.UIFigure, 'push');
            app.C1Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C1Button.Text = 'C1';
            app.D1Button = uibutton(app.UIFigure, 'push');
            app.D1Button.ButtonPushedFcn = createCallbackFcn(app, @D1ButtonPushed, true);
            app.D1Button.Text = 'D1';
            app.E1Button = uibutton(app.UIFigure, 'push');
            app.E1Button.ButtonPushedFcn = createCallbackFcn(app, @E1ButtonPushed, true);
            app.E1Button.Text = 'E1';
            app.F1Button = uibutton(app.UIFigure, 'push');
            app.F1Button.ButtonPushedFcn = createCallbackFcn(app, @F1ButtonPushed, true);
            app.F1Button.Text = 'F1';
            app.G1Button = uibutton(app.UIFigure, 'push');
            app.G1Button.ButtonPushedFcn = createCallbackFcn(app, @G1ButtonPushed, true);
            app.G1Button.Text = 'G1';
            app.A1Button = uibutton(app.UIFigure, 'push');
            app.A1Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A1Button.Text = 'A1';
            app.B1Button = uibutton(app.UIFigure, 'push');
            app.B1Button.ButtonPushedFcn = createCallbackFcn(app, @B1ButtonPushed, true);
            app.B1Button.Text = 'B1';
            app.C2Button = uibutton(app.UIFigure, 'push');
            app.C2Button.ButtonPushedFcn = createCallbackFcn(app, @C2ButtonPushed, true);
            app.C2Button.Text = 'C2';
            app.D2Button = uibutton(app.UIFigure, 'push');
            app.D2Button.ButtonPushedFcn = createCallbackFcn(app, @D2ButtonPushed, true);
            app.D2Button.Text = 'D2';
            app.E2Button = uibutton(app.UIFigure, 'push');
            app.E2Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.E2Button.Text = 'E2';
            app.F2Button = uibutton(app.UIFigure, 'push');
            app.F2Button.ButtonPushedFcn = createCallbackFcn(app, @F2ButtonPushed, true);
            app.F2Button.Text = 'F2';
            app.G2Button = uibutton(app.UIFigure, 'push');
            app.G2Button.ButtonPushedFcn = createCallbackFcn(app, @G2ButtonPushed, true);
            app.G2Button.Text = 'G2';
            app.A2Button = uibutton(app.UIFigure, 'push');
            app.A2Button.ButtonPushedFcn = createCallbackFcn(app, @A2ButtonPushed, true);
            app.A2Button.Text = 'A2';
            app.B2Button = uibutton(app.UIFigure, 'push');
            app.B2Button.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.B2Button.Text = 'B2';
            app.C3Button = uibutton(app.UIFigure, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.C3Button.Text = 'C3';
            app.D3Button = uibutton(app.UIFigure, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @D3ButtonPushed, true);
            app.D3Button.Text = 'D3';
            app.E3Button = uibutton(app.UIFigure, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @E3ButtonPushed, true);
            app.E3Button.Text = 'E3';
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.D5Button = uibutton(app.UIFigure, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.E5Button = uibutton(app.UIFigure, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.F5Button = uibutton(app.UIFigure, 'push');
            app.F5Button.ButtonPushedFcn = createCallbackFcn(app, @F5ButtonPushed, true);
            app.F5Button.Text = 'F5';
            app.G5Button = uibutton(app.UIFigure, 'push');
            app.G5Button.ButtonPushedFcn = createCallbackFcn(app, @G5ButtonPushed, true);
            app.G5Button.Text = 'G5';
            app.A5Button = uibutton(app.UIFigure, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A5ButtonPushed, true);
            app.A5Button.Text = 'A5';
            app.B5Button = uibutton(app.UIFigure, 'push');
            app.B5Button.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5Button.Text = 'B5';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'SallyDabbah '; 'HenSaadea'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end